classdef inverted_pendulum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        angle_velLabel          matlab.ui.control.Label
        angleLabel              matlab.ui.control.Label
        speedLabel              matlab.ui.control.Label
        posLabel                matlab.ui.control.Label
        KPanel                  matlab.ui.container.Panel
        KLabel                  matlab.ui.control.Label
        K4_Text                 matlab.ui.control.TextArea
        K3_Text                 matlab.ui.control.TextArea
        K2_Text                 matlab.ui.control.TextArea
        K1_Text                 matlab.ui.control.TextArea
        Panel_2                 matlab.ui.container.Panel
        Button                  matlab.ui.control.Button
        Reminder_Text           matlab.ui.control.TextArea
        Label_16                matlab.ui.control.Label
        Start_Button            matlab.ui.control.StateButton
        Model_Par               matlab.ui.container.Panel
        Model_Par_Button        matlab.ui.control.Button
        kgLabel_2               matlab.ui.control.Label
        Pole_m_Edit             matlab.ui.control.NumericEditField
        mLabel                  matlab.ui.control.Label
        mLabel_2                matlab.ui.control.Label
        Pole_l_Edit             matlab.ui.control.NumericEditField
        LLabel                  matlab.ui.control.Label
        kgLabel                 matlab.ui.control.Label
        Cart_m_Edit             matlab.ui.control.NumericEditField
        MLabel                  matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        Data_Show_Set           matlab.ui.container.Panel
        Ang_Vel_ChBox           matlab.ui.control.CheckBox
        Ang_ChBox               matlab.ui.control.CheckBox
        Pos_ChBox               matlab.ui.control.CheckBox
        Speed_ChBox             matlab.ui.control.CheckBox
        LQR_Set                 matlab.ui.container.Panel
        Cal_K_Button            matlab.ui.control.Button
        Q4_Edit                 matlab.ui.control.NumericEditField
        Label_15                matlab.ui.control.Label
        Label_14                matlab.ui.control.Label
        Label_13                matlab.ui.control.Label
        Label_12                matlab.ui.control.Label
        Label_11                matlab.ui.control.Label
        Label_10                matlab.ui.control.Label
        R_Edit                  matlab.ui.control.NumericEditField
        REditFieldLabel         matlab.ui.control.Label
        Label_9                 matlab.ui.control.Label
        Label_8                 matlab.ui.control.Label
        Label_7                 matlab.ui.control.Label
        Label_6                 matlab.ui.control.Label
        Label_5                 matlab.ui.control.Label
        Label_4                 matlab.ui.control.Label
        Q3_Edit                 matlab.ui.control.NumericEditField
        Q2_Edit                 matlab.ui.control.NumericEditField
        Q1_Edit                 matlab.ui.control.NumericEditField
        QLabel                  matlab.ui.control.Label
        State_Set               matlab.ui.container.Panel
        Mode_Lamp               matlab.ui.control.Lamp
        Speed_Lamp              matlab.ui.control.Lamp
        Pos_Lamp                matlab.ui.control.Lamp
        Model_Select            matlab.ui.control.DropDown
        Label_3                 matlab.ui.control.Label
        Speed_Slider            matlab.ui.control.Slider
        Label_2                 matlab.ui.control.Label
        Pos_Slider              matlab.ui.control.Slider
        Label                   matlab.ui.control.Label
        inverted_pendulumLabel  matlab.ui.control.Label
        UIAxes_2D               matlab.ui.control.UIAxes
        UIAxes_data             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        %模型参数
        Cart_m = 1.906;
        Pole_m = 0.109;
        Pole_len = 0.25;

        %LQR
        Q = [10 0 0 0;
            0 1 0 0;
            0 0 200 0;
            0 0 0 1];
        R = 0.0001;

        K
        
        %状态空间方程相关矩阵
        %连续系统
        A = [0  1        0        0;
            0  -0.0883  0.6293   0;
            0  0        0        1;
            0  -0.2357  27.8285  0];
        B = [0; 0.8832; 0; 2.3566];
        C = [1 0 0 0;
            0 0 1 0];
        D = [0; 0];

        G = [
            1.0000    0.0499    0.0008    0.0000
            0    0.9956    0.0318    0.0008
            0   -0.0003    1.0350    0.0506
            0   -0.0119    1.4074    1.0350 ];
        H =[0.0011;
            0.0441;
            0.0030;
            0.1189]
        Cd = [1 0 0 0;
            0 0 1 0];
        Dd = [0; 0];

        X_tar = [2; 0; 0; 0];
        X0 = [0; 0; 0; 0];
        X_last_state = [0; 0; 0; 0];
        X_pre_state = [0; 0; 0; 0];

        State_Num = 4;

        Sim_Step = 0.05;

        Xcl
        %图像范围
        x_2D_ran = [-10.0, 10.0];
        y_2D_ran = [-1.0, 9.0];

        x_Data_ran = [0, 20.0];
        y_Data_ran = [-10, 10];

        draw_data_state = [0 0 0 0 0];
        %绘画对象
        plot_obj_pos
        plot_obj_speed
        plot_obj_angle
        plot_obj_angle_vel
        %绘图状态
        plot_state = [0 0 0 0];
        %系统状态
        system_state = 0;
        %模式状态
        mode_state = 1;
        %滑动条状态
        slider_state = 0;

    end

    methods (Access = private)

        function draw_pend(app,y)

            %获取位置、角度数据
            L = 1.5;

            x = y(1);
            th = y(3);

            % 车子宽度
            W = 1 * sqrt(1.906);

            % 车子高度
            h = .5 * sqrt(1.906);

            % 轮胎半径
            wr = .5;

            % 小球半径
            mr = .8 * sqrt(0.109);

            % 位置高度
            y = wr/2 + h/2;

            %两个轮子起始点坐标
            w1x = x - 0.9 * W/2;
            w1y = 0;
            w2x = x + 0.9 * W/2 - wr;
            w2y = 0;

            %杆终点坐标
            px = x + L * sin(th);
            py = y + L * cos(th);

            plot(app.UIAxes_2D,[-20 20], [0 0], 'k', 'LineWidth', 2)
            hold(app.UIAxes_2D,'on');

            %绘制小车
            rectangle(app.UIAxes_2D,'Position', [x - W/2, y - h/2, W, h], 'Curvature', 0.1, 'FaceColor', [0.8 1 0.8], 'LineWidth', 1.5)
            rectangle(app.UIAxes_2D,'Position', [w1x, w1y, wr, wr], 'Curvature', 1, 'FaceColor', [0.5 0.5 0.7], 'LineWidth', 1.5)
            rectangle(app.UIAxes_2D,'Position', [w2x,w2y,wr,wr], 'Curvature', 1, 'FaceColor', [0.5 0.5 0.7], 'LineWidth', 1.5)

            %绘制摆臂
            plot(app.UIAxes_2D,[x px], [y py], 'k', 'LineWidth', 2)

            %绘制
            rectangle(app.UIAxes_2D,'Position', [px - mr/2, py - mr/2, mr, mr], 'Curvature', 1, 'FaceColor', [1 0.1 .1], 'LineWidth', 1.5)


            %设置坐标及刻度
            x_tic = app.x_2D_ran(1) : 0.5 : app.x_2D_ran(2);
            y_tic = app.y_2D_ran(1) : 0.5 : app.y_2D_ran(2);

            xlim(app.UIAxes_2D, app.x_2D_ran);
            ylim(app.UIAxes_2D, app.y_2D_ran);

            xticks(app.UIAxes_2D, x_tic);
            yticks(app.UIAxes_2D, y_tic);

            %axis equal
            drawnow
            hold(app.UIAxes_2D, 'off');


        end

        function plot_obj = draw_data(app, y, color, DisplayName)

            T = 0 : app.Sim_Step : 15;
            plot_obj = plot(app.UIAxes_data, T, y, 'color',color,'DisplayName',DisplayName);
            %                 legend(app.UIAxes_data,plot_obj);

        end

        function  ctr(app)
            KK = app.K;
            if(app.X_tar(2) ~= 0)
                KK(1) = 0;
            end

            %计算加入反馈后的状态空间方程矩阵
            GG = app.G - app.H * KK;
            HH = app.H;
            CC = app.Cd;
            DD = app.Dd;

            %设置离散时间及步长
            T = 0 : app.Sim_Step : 15;

            X_err = app.X_tar - 0;

            U = app.K * X_err * ones(size(T));

            [~, app.Xcl] = dlsim(GG, HH, CC, DD, U, app.X0);

            app.X_last_state = app.Xcl(end,:);

            if app.slider_state == 1
                hold_data(app);
                app.slider_state =0;
            end

            %figure
            for k=1:length(T)
                draw_pend(app,app.Xcl(k, :));
                app.X_pre_state = app.Xcl(k,:);
            end

            %                 app.Reminder_Text.Value = "倒立摆仿真实验结束";
            %                 while true
            %                     draw_pend(app,app.X_last_state);
            %                 end
            %                 app.Start_Button.Value = false;

        end

        function  hold_data(app)

            delete(app.plot_obj_pos);
            delete(app.plot_obj_speed);
            delete(app.plot_obj_angle);
            delete(app.plot_obj_angle_vel);

            if(app.plot_state(1) == 1)
                app.plot_obj_pos = draw_data(app, app.Xcl(:,1), 'g', 'pos');
                hold(app.UIAxes_data, "on");
            end
            if(app.plot_state(2) == 1)
                app.plot_obj_speed = draw_data(app, app.Xcl(:,2), 'r', 'speed');
                hold(app.UIAxes_data, "on");
            end
            if(app.plot_state(3) == 1)
                app.plot_obj_angle = draw_data(app, app.Xcl(:,3), 'b', 'angle');
                hold(app.UIAxes_data, "on");
            end
            if(app.plot_state(4) == 1)
                app.plot_obj_angle_vel = draw_data(app, app.Xcl(:,4), 'k', 'angle_vel');
                hold(app.UIAxes_data, "on");
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %设置坐标及刻度
            x_tic = app.x_Data_ran(1) : 1 : app.x_Data_ran(2);
            y_tic = app.y_Data_ran(1) : 1 : app.y_Data_ran(2);

            xlim(app.UIAxes_data, app.x_Data_ran);
            ylim(app.UIAxes_data, app.y_Data_ran);

            xticks(app.UIAxes_data, x_tic);
            yticks(app.UIAxes_data, y_tic);

            %设置坐标及刻度
            x_tic = app.x_2D_ran(1) : 0.5 : app.x_2D_ran(2);
            y_tic = app.y_2D_ran(1) : 0.5 : app.y_2D_ran(2);

            xlim(app.UIAxes_2D, app.x_2D_ran);
            ylim(app.UIAxes_2D, app.y_2D_ran);

            xticks(app.UIAxes_2D, x_tic);
            yticks(app.UIAxes_2D, y_tic);
            
            app.mode_state = 1;
            app.Speed_Lamp.Color = 'r';
            app.Pos_Lamp.Color = 'r';
            app.Mode_Lamp.Color = 'r';

        end

        % Value changed function: Model_Select
        function Model_SelectValueChanged(app, event)
            value = app.Model_Select.Value;
            if app.system_state == 0 || app.system_state == 1
                msgbox("模型参数未确认或K矩阵未计算")
            else
                if strcmp(value, "位置")
                    app.mode_state = 1;
                    app.Speed_Lamp.Color = 'r';
                    app.Pos_Lamp.Color = 'g';

                elseif strcmp(value, "速度")
                    app.mode_state = 2;
                    app.Speed_Lamp.Color = 'g';
                    app.Pos_Lamp.Color = 'r';

                end
            end
        end

        % Value changed function: Pos_Slider
        function Pos_SliderValueChanged(app, event)
            
            if app.mode_state == 1

                value = app.Pos_Slider.Value;

                app.X_tar(1) = value;
                app.X_tar(2) = 0;

                app.X_pre_state(2) = 0;
                app.X0 = app.X_pre_state;

                app.slider_state = 1;

                ctr(app);

            else

                msgbox("当前不是位置模式");
                
            end
        end

        % Value changed function: Speed_Slider
        function Speed_SliderValueChanged(app, event)
           
            if app.mode_state == 2

                value = app.Speed_Slider.Value;
                app.X_tar(2) = value;
                app.X_tar(1) = 0;
                app.X0 = app.X_pre_state;
                app.slider_state = 1;
                ctr(app);

            else 

                msgbox("当前不是速度模式");

            end

        end

        % Value changed function: Cart_m_Edit
        function Cart_m_EditValueChanged(app, event)
           
            value = app.Cart_m_Edit.Value;
            app.Cart_m = value;

        end

        % Value changed function: Pole_l_Edit
        function Pole_l_EditValueChanged(app, event)
           
            value = app.Pole_l_Edit.Value;
            app.Pole_len = value;

        end

        % Value changed function: Pole_m_Edit
        function Pole_m_EditValueChanged(app, event)
            
            value = app.Pole_m_Edit.Value;
            app.Pole_m = value;

        end

        % Value changed function: Q1_Edit
        function Q1_EditValueChanged(app, event)
            value = app.Q1_Edit.Value;
            app.Q(1,1) = value;
        end

        % Value changed function: Q2_Edit
        function Q2_EditValueChanged(app, event)
             
            value = app.Q2_Edit.Value;
             app.Q(2,2) = value;

        end

        % Value changed function: Q3_Edit
        function Q3_EditValueChanged(app, event)
            value = app.Q3_Edit.Value;
            app.Q(3,3) = value;
        end

        % Value changed function: Q4_Edit
        function Q4_EditValueChanged(app, event)
            
            value = app.Q4_Edit.Value;
            app.Q(4,4) = value;

        end

        % Value changed function: R_Edit
        function R_EditValueChanged(app, event)
            
            value = app.R_Edit.Value;
            app.R = value;

        end

        % Button pushed function: Cal_K_Button
        function Cal_K_ButtonPushed(app, event)

         if app.system_state == 0
             msgbox("模型参数未设置");   
         else
             %计算K矩阵
             app.system_state = 2;
             if any(diag(app.Q)~=0) && app.R~=0 %判断是否为正定矩阵
                [app.K,~] = dlqr(app.G, app.H, app.Q, app.R);
    
                app.K1_Text.Value = string(app.K(1));
                app.K2_Text.Value = string(app.K(2));
                app.K3_Text.Value = string(app.K(3));
                app.K4_Text.Value = string(app.K(4));
    
             else
                msgbox("Q或者R为空");
    
                app.K1_Text.Value = '0';
                app.K2_Text.Value = '0';
                app.K3_Text.Value = '0';
                app.K4_Text.Value = '0';
    
             end
         end
        

        end

        % Value changed function: Start_Button
        function Start_ButtonValueChanged(app, event)
           
            value = app.Start_Button.Value;
            if any(app.K ~= 0)

                app.Reminder_Text.Value = "正在进行倒立摆仿真实验";

                app.Mode_Lamp.Color = 'g';    
                app.Pos_Lamp.Color = 'g';

                ctr(app);

            else 

                msgbox("K矩阵为0，请重新计算K矩阵");

            end

        end

        % Button pushed function: Model_Par_Button
        function Model_Par_ButtonPushed(app, event)
            if app.system_state == 0 || app.system_state == 1

                if (app.Cart_m ~= 0) && (app.Pole_m ~= 0) && (app.Pole_len ~= 0)

                   app.system_state = 1;
                   [app.G,app.H,app.Cd,app.Dd] = c2dm(app.A, app.B, app.C, app.D, app.Sim_Step);
    
                else 
    
                    msgbox("模型参数不能为0");
    
                end

            else

                msgbox("模型参数已设置、请重置");

            end

            % app.K1_Text.Value = string(app.Cart_m);
            % app.K2_Text.Value = string(app.Pole_m);
            % app.K3_Text.Value = string(app.Pole_len);

        end

        % Value changed function: Pos_ChBox
        function Pos_ChBoxValueChanged(app, event)
            value = app.Pos_ChBox.Value;

            if value == true
                
                app.plot_obj_pos = draw_data(app, app.Xcl(:,1), 'g', 'pos');
                app.plot_state(1) =1;
                hold(app.UIAxes_data, "on");

            else 
                app.plot_state(1) =0;
                delete(app.plot_obj_pos);

            end

        end

        % Value changed function: Speed_ChBox
        function Speed_ChBoxValueChanged(app, event)
            value = app.Speed_ChBox.Value;

            if value == true

                app.plot_state(2) =1;
                app.plot_obj_speed = draw_data(app, app.Xcl(:,2), 'r', 'speed');
                hold(app.UIAxes_data, "on");

            else 

                app.plot_state(2) =0;
                delete(app.plot_obj_speed);

            end

        end

        % Value changed function: Ang_ChBox
        function Ang_ChBoxValueChanged(app, event)
            value = app.Ang_ChBox.Value;

            if value == true

                app.plot_state(3) =1;
                app.plot_obj_angle = draw_data(app, app.Xcl(:,3), 'b', 'angle');
                hold(app.UIAxes_data, "on");

            else 

                app.plot_state(3) =0;
                delete(app.plot_obj_angle);

            end

        end

        % Value changed function: Ang_Vel_ChBox
        function Ang_Vel_ChBoxValueChanged(app, event)
            value = app.Ang_Vel_ChBox.Value;

            if value == true

                app.plot_state(4) =1;
                app.plot_obj_angle_vel = draw_data(app, app.Xcl(:,4), 'k', 'angle_vel');
                hold(app.UIAxes_data, "on");

            else 

                app.plot_state(4) =0;
                delete(app.plot_obj_angle_vel);

            end

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
           
            delete(app.UIFigure);
            run inverted_pendulum.mlapp;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_data = uiaxes(app.UIFigure);
            title(app.UIAxes_data, 'State')
            xlabel(app.UIAxes_data, 'X')
            ylabel(app.UIAxes_data, 'Y')
            zlabel(app.UIAxes_data, 'Z')
            app.UIAxes_data.XTick = [];
            app.UIAxes_data.YTick = [];
            app.UIAxes_2D = uiaxes(app.UIFigure);
            title(app.UIAxes_2D, '2D')
            xlabel(app.UIAxes_2D, 'X')
            ylabel(app.UIAxes_2D, 'Y')
            zlabel(app.UIAxes_2D, 'Z')
            app.UIAxes_2D.XTick = [];
            app.UIAxes_2D.YTick = [];
            app.inverted_pendulumLabel = uilabel(app.UIFigure);
            app.inverted_pendulumLabel.Text = 'inverted_pendulum';
            app.State_Set = uipanel(app.UIFigure);
            app.State_Set.Title = '状态设定';
            app.Label = uilabel(app.State_Set);
            app.Label.Text = '位置';
            app.Pos_Slider = uislider(app.State_Set);
            app.Pos_Slider.Limits = [-10 10];
            app.Pos_Slider.MajorTicks = [-10 -6 -2 2 6 10];
            app.Pos_Slider.MajorTickLabels = {'-10', '-6', '-2', '2', '6', '10'};
            app.Pos_Slider.ValueChangedFcn = createCallbackFcn(app, @Pos_SliderValueChanged, true);
            app.Label_2 = uilabel(app.State_Set);
            app.Label_2.Text = '速度';
            app.Speed_Slider = uislider(app.State_Set);
            app.Speed_Slider.Limits = [-2 2];
            app.Speed_Slider.MajorTicks = [-3 -2 -1 0 1 2 3];
            app.Speed_Slider.MajorTickLabels = {'-3', '-2', '-1', '0', '1', '2', '3'};
            app.Speed_Slider.ValueChangedFcn = createCallbackFcn(app, @Speed_SliderValueChanged, true);
            app.Label_3 = uilabel(app.State_Set);
            app.Label_3.Text = '模式设置';
            app.Model_Select = uidropdown(app.State_Set);
            app.Model_Select.Items = {'位置', '速度'};
            app.Model_Select.ValueChangedFcn = createCallbackFcn(app, @Model_SelectValueChanged, true);
            app.Model_Select.Value = '位置';
            app.Pos_Lamp = uilamp(app.State_Set);
            app.Speed_Lamp = uilamp(app.State_Set);
            app.Mode_Lamp = uilamp(app.State_Set);
            app.LQR_Set = uipanel(app.UIFigure);
            app.LQR_Set.Title = 'LQR最优控制';
            app.QLabel = uilabel(app.LQR_Set);
            app.QLabel.Text = 'Q=';
            app.Q1_Edit = uieditfield(app.LQR_Set, 'numeric');
            app.Q1_Edit.ValueChangedFcn = createCallbackFcn(app, @Q1_EditValueChanged, true);
            app.Q2_Edit = uieditfield(app.LQR_Set, 'numeric');
            app.Q2_Edit.ValueChangedFcn = createCallbackFcn(app, @Q2_EditValueChanged, true);
            app.Q3_Edit = uieditfield(app.LQR_Set, 'numeric');
            app.Q3_Edit.ValueChangedFcn = createCallbackFcn(app, @Q3_EditValueChanged, true);
            app.Label_4 = uilabel(app.LQR_Set);
            app.Label_4.Text = '0';
            app.Label_5 = uilabel(app.LQR_Set);
            app.Label_5.Text = '0';
            app.Label_6 = uilabel(app.LQR_Set);
            app.Label_6.Text = '0';
            app.Label_7 = uilabel(app.LQR_Set);
            app.Label_7.Text = '0';
            app.Label_8 = uilabel(app.LQR_Set);
            app.Label_8.Text = '0';
            app.Label_9 = uilabel(app.LQR_Set);
            app.Label_9.Text = '0';
            app.REditFieldLabel = uilabel(app.LQR_Set);
            app.REditFieldLabel.Text = 'R=';
            app.R_Edit = uieditfield(app.LQR_Set, 'numeric');
            app.R_Edit.ValueChangedFcn = createCallbackFcn(app, @R_EditValueChanged, true);
            app.Label_10 = uilabel(app.LQR_Set);
            app.Label_10.Text = '0';
            app.Label_11 = uilabel(app.LQR_Set);
            app.Label_11.Text = '0';
            app.Label_12 = uilabel(app.LQR_Set);
            app.Label_12.Text = '0';
            app.Label_13 = uilabel(app.LQR_Set);
            app.Label_13.Text = '0';
            app.Label_14 = uilabel(app.LQR_Set);
            app.Label_14.Text = '0';
            app.Label_15 = uilabel(app.LQR_Set);
            app.Label_15.Text = '0';
            app.Q4_Edit = uieditfield(app.LQR_Set, 'numeric');
            app.Q4_Edit.ValueChangedFcn = createCallbackFcn(app, @Q4_EditValueChanged, true);
            app.Cal_K_Button = uibutton(app.LQR_Set, 'push');
            app.Cal_K_Button.ButtonPushedFcn = createCallbackFcn(app, @Cal_K_ButtonPushed, true);
            app.Cal_K_Button.Text = '计算K矩阵';
            app.Data_Show_Set = uipanel(app.UIFigure);
            app.Data_Show_Set.Title = '数据展示';
            app.Speed_ChBox = uicheckbox(app.Data_Show_Set);
            app.Speed_ChBox.ValueChangedFcn = createCallbackFcn(app, @Speed_ChBoxValueChanged, true);
            app.Speed_ChBox.Text = '速度';
            app.Pos_ChBox = uicheckbox(app.Data_Show_Set);
            app.Pos_ChBox.ValueChangedFcn = createCallbackFcn(app, @Pos_ChBoxValueChanged, true);
            app.Pos_ChBox.Text = '位置';
            app.Ang_ChBox = uicheckbox(app.Data_Show_Set);
            app.Ang_ChBox.ValueChangedFcn = createCallbackFcn(app, @Ang_ChBoxValueChanged, true);
            app.Ang_ChBox.Text = '角度';
            app.Ang_Vel_ChBox = uicheckbox(app.Data_Show_Set);
            app.Ang_Vel_ChBox.ValueChangedFcn = createCallbackFcn(app, @Ang_Vel_ChBoxValueChanged, true);
            app.Ang_Vel_ChBox.Text = '角速度';
            app.Model_Par = uipanel(app.UIFigure);
            app.Model_Par.Title = '模型参数';
            app.Image = uiimage(app.Model_Par);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'inverted_pendulum.png');
            app.MLabel = uilabel(app.Model_Par);
            app.MLabel.Text = 'M =';
            app.Cart_m_Edit = uieditfield(app.Model_Par, 'numeric');
            app.Cart_m_Edit.ValueChangedFcn = createCallbackFcn(app, @Cart_m_EditValueChanged, true);
            app.kgLabel = uilabel(app.Model_Par);
            app.kgLabel.Text = 'kg';
            app.LLabel = uilabel(app.Model_Par);
            app.LLabel.Text = 'L =';
            app.Pole_l_Edit = uieditfield(app.Model_Par, 'numeric');
            app.Pole_l_Edit.ValueChangedFcn = createCallbackFcn(app, @Pole_l_EditValueChanged, true);
            app.mLabel_2 = uilabel(app.Model_Par);
            app.mLabel_2.Text = 'm';
            app.mLabel = uilabel(app.Model_Par);
            app.mLabel.Text = 'm =';
            app.Pole_m_Edit = uieditfield(app.Model_Par, 'numeric');
            app.Pole_m_Edit.ValueChangedFcn = createCallbackFcn(app, @Pole_m_EditValueChanged, true);
            app.kgLabel_2 = uilabel(app.Model_Par);
            app.kgLabel_2.Text = 'kg';
            app.Model_Par_Button = uibutton(app.Model_Par, 'push');
            app.Model_Par_Button.ButtonPushedFcn = createCallbackFcn(app, @Model_Par_ButtonPushed, true);
            app.Model_Par_Button.Text = '确定';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '启停控制';
            app.Start_Button = uibutton(app.Panel_2, 'state');
            app.Start_Button.ValueChangedFcn = createCallbackFcn(app, @Start_ButtonValueChanged, true);
            app.Start_Button.Text = '开始';
            app.Label_16 = uilabel(app.Panel_2);
            app.Label_16.Text = '提示信息';
            app.Reminder_Text = uitextarea(app.Panel_2);
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '复位';
            app.KPanel = uipanel(app.UIFigure);
            app.KPanel.Title = 'K矩阵';
            app.K1_Text = uitextarea(app.KPanel);
            app.K2_Text = uitextarea(app.KPanel);
            app.K3_Text = uitextarea(app.KPanel);
            app.K4_Text = uitextarea(app.KPanel);
            app.KLabel = uilabel(app.KPanel);
            app.KLabel.Text = 'K =  ';
            app.posLabel = uilabel(app.UIFigure);
            app.posLabel.Text = '——pos';
            app.speedLabel = uilabel(app.UIFigure);
            app.speedLabel.Text = '——speed';
            app.angleLabel = uilabel(app.UIFigure);
            app.angleLabel.Text = '——angle';
            app.angle_velLabel = uilabel(app.UIFigure);
            app.angle_velLabel.Text = '——angle_vel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = inverted_pendulum

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end